classdef Menu2_CCG_MolSim_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OHbulkcovButton           matlab.ui.control.Button
        OHsurfacecovButton        matlab.ui.control.Button
        AngularDistButton         matlab.ui.control.Button
        RadialanalysisButton      matlab.ui.control.Button
        BaderHeatmapButton        matlab.ui.control.Button
        MassDensityplotButton     matlab.ui.control.Button
        PaseEnergyfileButton      matlab.ui.control.Button
        SplitXYZfileButton        matlab.ui.control.Button
        TrajectoryEditField       matlab.ui.control.EditField
        TrajectoryEditFieldLabel  matlab.ui.control.Label
        SystemEditField           matlab.ui.control.EditField
        SystemLabel               matlab.ui.control.Label
        BaseFolderEditField       matlab.ui.control.EditField
        BaseFolderLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        BaseFldr % Description
        system
        Trajectory 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BaseFolderEditField
        function BaseFolderEditFieldValueChanged(app, event)
            app.BaseFldr = app.BaseFolderEditField.Value;
            
        end

        % Value changed function: SystemEditField
        function SystemEditFieldValueChanged(app, event)
            app.system = app.SystemEditField.Value;
            
        end

        % Value changed function: TrajectoryEditField
        function TrajectoryEditFieldValueChanged(app, event)
            app.Trajectory = app.TrajectoryEditField.Value;
            
        end

        % Button pushed function: MassDensityplotButton
        function MassDensityplotButtonPushed(app, event)
%clear all;
%close all;

PathSep =  setOSpathSep;
            % % get the names of atoms from original xyz input file
[~, ~, AtomIndx, ~, ~, ~, ~] = getAtomInfoFromInput(app.BaseFldr, app.system);

ABC = getABCvectors(app.BaseFldr, app.system);
% [xyz, XYZ, Indx, ~, ~, nAtoms, startConfig, nConfigs, StepNum] = ReadAndParsexyz(BaseFldr, system, Trajectory, ABC, [pi/2; 1; 1]);
[xyz, XYZ, Indx, Atoms, AtomList, nAtoms, startConfig, nConfigs, StepNum] = ReadAndParsexyz_new(app.BaseFldr, app.system, app.Trajectory, ABC, [0; 0; 0]);
% [xyz, XYZ, Indx, Atoms, AtomList, nAtoms, startConfig, nConfigs, StepNum] = ReadAndParsexyz_new(BaseFldr, system, Trajectory, ABC, [pi/2; 1; 1]);


% % % % % %% Modify which O atoms go into mass density accoring to explicit naming in
% % % % % % input xyz. AtomIndx is the Index of atoms by name from input.xyz. Modify
% % % % % % % get the names of atoms from original xyz input file
% % % % % % [~, OIndxxyz] = ismember([AtomIndx.O; AtomIndx.OtL; AtomIndx.OtU; AtomIndx.OtS] , Indx.O);
% % % % % [~, OIndxxyz] = ismember([AtomIndx.O] , Indx.O);
% % % % % xyz.O = xyz.O(:,OIndxxyz,:);
%%

[Dens_O, Dens_H, TotDen, AveDen, z] = getDensityProfile(xyz, ABC);
% [Dens_O, Dens_H, TotDen, AveDen, z] = getCylindricalDensity(xyz, ABC);
% [Dens_O, Dens_H, Dens_F, TotDen, AveDen, z] = getDensityProfile(xyz, ABC);
% [Dens_O, Dens_H, Dens_Na, Dens_Cl, TotDen, AveDen, z] = getDensityProfile(xyz, ABC);

% [FirstLayerIndx, SecondLayerIndx, ThirdLayerIndx, FourthLayerIndx, MinimaZ] = getWaterLayerIndices(Indx, XYZ, Dens_O, z);

zmax = ABC(3);
bins = length(z) + 1;
% Plot each snapshot
figure
hold on
set(gcf, 'position', [377         423        1123         420]);
xlabel('z (Ang)');
ylabel('Density (kgm^{-3})');
set(gca, 'xlim', [0 ABC(3)], 'ylim', [0 2500]);
% include profile for every snapshot
for i = 1:size(TotDen,2)
    plot(z, TotDen(:,i), '--', 'linewidth', 0.25)
%     plot(z, TotDen(:,i), '--', 'linewidth', 0.25, 'color', 'r')
end
plot(z, sum(TotDen,2)/(nConfigs-startConfig+1), 'linewidth', 1.5, 'color', 'k')
plot([z(1) z(end)], [mean(AveDen) mean(AveDen)], ':', 'color', [0.6 0.6 0.6])
plot([z((bins/2)-round(3/(zmax/(bins-1))/2)) z((bins/2)-round(3/(zmax/(bins-1))/2))], [0 2500], ':', 'color', [0.6 0.6 0.6])
plot([z((bins/2)+round(3/(zmax/(bins-1))/2)) z((bins/2)+round(3/(zmax/(bins-1))/2))], [0 2500], ':', 'color', [0.6 0.6 0.6])
hold off

figure
hold on
set(gcf, 'position', [377         423        1123         420]);
xlabel('z (Ang)');
ylabel('Density (kgm^{-3})');
set(gca, 'xlim', [0 ABC(3)], 'ylim', [0 2500]);

plot(z, sum(TotDen,2)/(nConfigs-startConfig+1), 'linewidth', 1.5, 'color', 'k')
plot(z, sum(Dens_H,2)/(nConfigs-startConfig+1), 'linewidth', 1.5, 'color', 'b')
plot(z, sum(Dens_O,2)/(nConfigs-startConfig+1), 'linewidth', 1.5, 'color', 'r')

plot([z(1) z(end)], [mean(AveDen) mean(AveDen)], ':', 'color', [0.6 0.6 0.6])
plot([z((bins/2)-round(5/(zmax/(bins-1))/2)) z((bins/2)-round(5/(zmax/(bins-1))/2))], [0 2500], ':', 'color', [0.6 0.6 0.6]) % there might be a bug here since zmax isn't max(z)? 
plot([z((bins/2)+round(5/(zmax/(bins-1))/2)) z((bins/2)+round(5/(zmax/(bins-1))/2))], [0 2500], ':', 'color', [0.6 0.6 0.6])

legend('Water', 'H', 'O', 'Ave. Bulk Density', 'location', 'northeast');

hold off
        end

        % Button pushed function: SplitXYZfileButton
        function SplitXYZfileButtonPushed(app, event)
%clear all;
%close all;

%--------------------------------------------------------------------------
%--------------------------------------------------------------------------
%--------------------------------------------------------------------------

% Set up the progress bar axis
fh = clf();
ax = axes(fh,'Position',[.1 .4 .8 .05],'box','on','xtick',[],'ytick',[],...
    'color',[0.9375 0.9375 0.9375],'xlim',[0,1],'ylim',[0,1]); %gray94
title(ax,'Progress')

% Create empty patch that will be updated
ph = patch(ax,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow

% Create the percent-complete text that will be updated
th = text(ax,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right'); 

%--------------------------------------------------------------------------
%--------------------------------------------------------------------------
%--------------------------------------------------------------------------


PathSep =  setOSpathSep;
            % nSampleSteps = 1000; % sampling in units of number of steps
nSampleSteps = 100; % sampling in units of number of steps
% nSampleSteps = 1; % sampling in units of number of steps
InitalStepOverride = []; % Use to set initial step manually. Set to [] to use entire trajectory
% InitalStepOverride = 16000; % Use to set initial step manually. Set to [] to use entire trajectory

ABC = getABCvectors(app.BaseFldr, app.system);
[xyz, XYZ, Indx, Atoms, AtomList, nAtoms, startConfig, nConfigs, StepNum] = ReadAndParsexyz_new(app.BaseFldr, app.system, app.Trajectory, ABC, [0; 0; 0]);

fidout = fopen([app.BaseFldr app.system '/final.xyz'], 'w');
fprintf(fidout,[num2str(nAtoms) newline]);
fprintf(fidout,['i = ' num2str(StepNum(end)) newline]);
for i = 1:nAtoms
    fprintf(fidout,[Atoms(i,:) pad(extractBefore(num2str(XYZ(end, i, 1), '%#.10g'),10),20) pad(extractBefore(num2str(XYZ(end, i, 2), '%#.10g'),10),20) pad(extractBefore(num2str(XYZ(end, i, 3), '%#.10g'),10),20) newline]);
end
fclose(fidout);

first = ceil(StepNum(1)/nSampleSteps)*nSampleSteps;
last = floor(StepNum(end)/nSampleSteps)*nSampleSteps;

if ~isempty(InitalStepOverride)
    first = InitalStepOverride;
end

fidout = fopen([app.BaseFldr app.system PathSep app.system '_' num2str(first) 'to' num2str(last) '_' num2str(nSampleSteps) 'step.xyz'], 'w'); % AIMD
for i = first-StepNum(1)+1:nSampleSteps:last-StepNum(1)+1

     % update patch size and percentage text
    ph.XData = [0 i/(last-StepNum(1)+1) i/(last-StepNum(1)+1) 0]; 
    th.String = sprintf('%.0f%%',round(i/(last-StepNum(1)+1)*100)); 
    drawnow %update graphics


    disp(['Writing sample trajectory... ' num2str(100*(i/(last-StepNum(1)+1))) ' % complete']);
    fprintf(fidout,[num2str(nAtoms) newline]);
    fprintf(fidout,['i = ' num2str(StepNum(i)) newline]);

    for j = 1:nAtoms
        fprintf(fidout,[Atoms(j,:) pad(extractBefore(num2str(XYZ(i, j, 1), '%#.10g'),10),20) pad(extractBefore(num2str(XYZ(i, j, 2), '%#.10g'),10),20) pad(extractBefore(num2str(XYZ(i, j, 3), '%#.10g'),10),20) newline]);

    end
end
fclose(fidout);

CP2kOptimPathParse(app.BaseFldr,app.system,[app.system '_' num2str(first) 'to' num2str(last) '_' num2str(nSampleSteps) 'step.xyz']);
CP2kOptimPathParse(app.BaseFldr,app.system,['final.xyz']);
        end

        % Button pushed function: PaseEnergyfileButton
        function PaseEnergyfileButtonPushed(app, event)
%--------------------------------------------------------------------------
%--------------------------------------------------------------------------
%--------------------------------------------------------------------------

% Set up the progress bar axis
fh = clf();
ax = axes(fh,'Position',[.1 .4 .8 .05],'box','on','xtick',[],'ytick',[],...
    'color',[0.9375 0.9375 0.9375],'xlim',[0,1],'ylim',[0,1]); %gray94
title(ax,'Progress')

% Create empty patch that will be updated
ph = patch(ax,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow

% Create the percent-complete text that will be updated
th = text(ax,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right'); 
%--------------------------------------------------------------------------
%--------------------------------------------------------------------------
%--------------------------------------------------------------------------
            
%--------------------------------------------------------------------------
%--------------------------------------------------------------------------
%--------------------------------------------------------------------------
% % Create processing loop that updates the progress bar
% n = 100; 
% for i = 1:n
%     % update patch size and percentage text
%     ph.XData = [0 i/n i/n 0]; 
%     th.String = sprintf('%.0f%%',round(i/n*100)); 
%     drawnow %update graphics
%        
% end
%--------------------------------------------------------------------------
%--------------------------------------------------------------------------
%--------------------------------------------------------------------------

PathSep = setOSpathSep;
% Set the number of steps before the end that are used to compute averages
SampleRange = 6000;
% SampleRange = 20000;

% Choose whether to plot an inset
% Inset = 'Yes';
Inset = 'No';

Allfldrs = dir([app.BaseFldr app.system '*']);

for i = 1:length(Allfldrs)

     % update patch size and percentage text
    ph.XData = [0 i/length(Allfldrs) i/length(Allfldrs) 0]; 
    th.String = sprintf('%.0f%%',round(i/length(Allfldrs)*100)); 
    drawnow %update graphics


    flname = dir([app.BaseFldr Allfldrs(i).name setOSpathSep '*.ener']);
    EnerFile = [app.BaseFldr Allfldrs(i).name setOSpathSep flname.name];

    if ~isempty(flname)
        disp(['Parsing energy file for ' Allfldrs(i).name '...']);

        fid = fopen(EnerFile);
        data = readmatrix(EnerFile, 'NumHeaderLines',1, 'FileType', 'Text');
    else
        warning('Energy file does not exist, exiting...')
        return
    end

    fig1 = figure;
    set(gcf, 'color', 'w', 'position', [388   417   592   420])
    ax1 = axes;
    h1 = plot(ax1, data(1:end,2)/1000, 27.211399*data(1:end,5), '-', 'linewidth', 1, 'color', 'r');
    title(['Ave. PE for last ' num2str(SampleRange/2000) ' ps = ' num2str(mean(27.211399*data(end-SampleRange:end,5)),'%.2f') ' \pm ' num2str(std(27.211399*data(end-SampleRange:end,5)),'%.2f') ' eV']);
    ylabel(ax1, 'Potential Energy (eV)')
    xlabel(ax1, 'Time (ps)')
    set(ax1, 'fontsize', 12)
    if size(data,1) > SampleRange && strcmp(Inset, 'Yes')
        ax2 = axes('Position',[0.64 0.6 0.25 0.2]);
        h2 = plot(ax2, data(end-SampleRange:end,2)/1000, 27.211399*data(end-SampleRange:end,5), '-', 'linewidth',0.5, 'color', 'r');
        ylabel(ax2, 'P. E. (eV)')
        xlabel(ax2, 'Time (ps)')
        set(ax2, 'fontsize', 8, 'xlim', [data(end-SampleRange,2)/1000 data(end,2)/1000] )

    end

    fig2 = figure;
    set(gcf, 'color', 'w', 'position', [388   417   592   420])
    ax1 = axes;
    h1 = plot(ax1, data(1:end,2)/1000, 27.211399*data(1:end,3), '-', 'linewidth', 1, 'color', [0 0.5 0]);
    title(['Ave. KE for last ' num2str(SampleRange/2000) ' ps = ' num2str(mean(27.211399*data(end-SampleRange:end,3)),'%.2f') ' \pm ' num2str(std(27.211399*data(end-SampleRange:end,3)),'%.2f') ' eV']);
    ylabel(ax1, 'Kinetic Energy (eV)')
    xlabel(ax1, 'Time (ps)')
    set(ax1, 'fontsize', 12)
    if size(data,1) > SampleRange && strcmp(Inset, 'Yes')
        ax2 = axes('Position',[0.64 0.6 0.25 0.2]);
        h2 = plot(ax2, data(end-SampleRange:end,2)/1000, 27.211399*data(end-SampleRange:end,3), '-', 'linewidth',0.5, 'color', [0 0.5 0]);
        ylabel(ax2, 'K. E. (eV)')
        xlabel(ax2, 'Time (ps)')
        set(ax2, 'fontsize', 8, 'xlim', [data(end-SampleRange,2)/1000 data(end,2)/1000] )
    end

    fig3 = figure;
    set(gcf, 'color', 'w', 'position', [388   417   592   420])
    ax1 = axes;
    h1 = plot(ax1, data(1:end,2)/1000, 27.211399*data(1:end,6), '-', 'linewidth', 1, 'color', 'b');
    % h2 = plot(ax1, data(1:end,2)/1000, 27.211399*(data(1:end,3)+data(1:end,5)), '-', 'linewidth', 1, 'color', 'm');
    title(['Ave. CQ for last ' num2str(SampleRange/2000) ' ps = ' num2str(mean(27.211399*data(end-SampleRange:end,6)),'%.2f') ' \pm ' num2str(std(27.211399*data(end-SampleRange:end,6)),'%.2f') ' eV']);
    ylabel(ax1, 'Conserved Quantity (eV)')
    xlabel(ax1, 'Time (ps)')
    set(ax1, 'fontsize', 12)
    if size(data,1) > SampleRange && strcmp(Inset, 'Yes')
        ax2 = axes('Position',[0.64 0.6 0.25 0.2]);
        h2 = plot(ax2, data(end-SampleRange:end,2)/1000, 27.211399*data(end-SampleRange:end,6), '-', 'linewidth',0.5, 'color', 'b');
        ylabel(ax2, 'C. Q. (eV)')
        xlabel(ax2, 'Time (ps)')
        set(ax2, 'fontsize', 8, 'xlim', [data(end-SampleRange,2)/1000 data(end,2)/1000] )
    end
    CQDrift = 27.211399*data(end-SampleRange:end,6)-detrend(27.211399*data(end-SampleRange:end,6));
    dCQDriftdt = mean(diff(CQDrift))*2000;

    fig4 = figure;
    set(gcf, 'color', 'w', 'position', [388   417   592   420])
    ax1 = axes;
    h1 = plot(ax1, data(1:end,2)/1000, data(1:end,4), '-', 'linewidth', 1, 'color', 'k');
    title(['Ave. Temp. for last ' num2str(SampleRange/2000) ' ps = ' num2str(mean(data(end-SampleRange:end,4)),'%.2f') ' \pm ' num2str(std(data(end-SampleRange:end,4)),'%.2f') ' K']);
    ylabel(ax1, 'Temperature (K)')
    xlabel(ax1, 'Time (ps)')
    set(ax1, 'fontsize', 12)
    if size(data,1) > SampleRange && strcmp(Inset, 'Yes')
        ax2 = axes('Position',[0.64 0.6 0.25 0.2]);
        h2 = plot(ax2, data(end-SampleRange:end,2)/1000, data(end-SampleRange:end,4), '-', 'linewidth',0.5, 'color', 'k');
        ylabel(ax2, 'Temp. (K)')
        xlabel(ax2, 'Time (ps)')
        set(ax2, 'fontsize', 8, 'xlim', [data(end-SampleRange,2)/1000 data(end,2)/1000] )
    end
    
    TDrift = data(end-SampleRange:end,4)-detrend(data(end-SampleRange:end,4));
    dTDriftdt = mean(diff(TDrift))*2000;

    exportgraphics(fig1,[app.BaseFldr Allfldrs(i).name setOSpathSep Allfldrs(i).name '_PE.jpg'],'Resolution',300)
    exportgraphics(fig2,[app.BaseFldr Allfldrs(i).name setOSpathSep Allfldrs(i).name '_KE.jpg'],'Resolution',300)
    exportgraphics(fig3,[app.BaseFldr Allfldrs(i).name setOSpathSep Allfldrs(i).name '_CQ.jpg'],'Resolution',300)
    exportgraphics(fig4,[app.BaseFldr Allfldrs(i).name setOSpathSep Allfldrs(i).name '_Temp.jpg'],'Resolution',300)

    tLast(i) = data(end,2)/1000;
    Range(i) = SampleRange/2000;

    Temp(i) = mean(data(end-SampleRange:end,4));
    TempStd(i) = std(data(end-SampleRange:end,4));
    TempDrift(i) = dTDriftdt;

    TotEng(i) = mean(27.211399*data(end-SampleRange:end,3))+mean(27.211399*data(end-SampleRange:end,5));
    TotEngStd(i) = std(27.211399*data(end-SampleRange:end,6));
    TotEngDrift(i) = dCQDriftdt;

    disp(['Ave. Total Energy for last ' num2str(SampleRange/2000) ' ps = ' num2str(TotEng(i)) ' eV']);
    disp(['Std. Dev. = ' num2str(TotEngStd(i)) ' eV']);
    disp(['Drift = ' num2str(TotEngDrift(i)) ' eV/ps']);

    disp(['Ave. Temperature for last ' num2str(SampleRange/2000) ' ps = ' num2str(mean(data(end-SampleRange:end,4))) ' K']);
    disp(['Std. Dev. = ' num2str(std(data(end-SampleRange:end,4))) ' K']);
    disp(['Drift = ' num2str(dTDriftdt) ' K/ps']);
    % disp(['Ave. Total Energy for last 1 ps = ' num2str(mean(27.211399*data(end-SampleRange:end,6)))]);

    [Atoms, AtomList, Indx, Indxfns, Kinds, Elements, PP] = getAtomInfoFromInput(app.BaseFldr, Allfldrs(i).name);
    AtomCount(i) = size(Atoms,1);
    OAtomCount(i) = size(Indx.O,1);
    HAtomCount(i) = size(Indx.H,1);
end

if length(Allfldrs) > 1
    T = table({Allfldrs.name}', AtomCount', OAtomCount', HAtomCount', tLast', Range', TotEng', TotEngStd', TotEngDrift', Temp', TempStd', TempDrift');
    T.Properties.VariableNames = {'System', 'Atom Count', 'O Atom Count', 'H Atom Count',  'Time (ps)', 'Sampled (ps)', 'Ave. Total Energy (eV)', 'Std. Dev. (eV)', 'Drift (eV)', 'Ave. Temperature (K)', 'Std. Dev. (K)', 'Drift (K)'};
    writetable(T, [app.BaseFldr 'Data.xlsx'])
end


        end

        % Button pushed function: AngularDistButton
        function AngularDistButtonPushed(app, event)
ABC = getABCvectors(app.BaseFldr, app.system);
[xyz, XYZ, Indx, Atoms, AtomList, nAtoms, startConfig, nConfigs, StepNum] = ReadAndParsexyz_new(app.BaseFldr, app.system, app.Trajectory, ABC, [0; 0; 0]);

[~, ~, AtomIndx, ~, ~, ~, ~] = getAtomInfoFromInput(app.BaseFldr, app.system);
%[Dens_O, Dens_H, TotDen, AveDen, z] = getDensityProfile(xyz, ABC, 120);
[Dens_O, Dens_H, TotDen, AveDen, z] = getDensityProfile(xyz, ABC);

 prompt = "Is this an oxide ('y'/'n'): ";
 oxide = input(prompt);
for snap = startConfig:nConfigs
%     disp(['Processing snapshot ' num2str(StepNum(snap)) ' - ' num2str(100*(snap/nConfigs)) ' % complete']);
   
    if (oxide == 'n')
        [FirstLayerIndx, SecondLayerIndx, MinimaZ] = getWaterLayerIndicesPerSnap_new(Indx, XYZ, Dens_O, z);
    elseif(oxide=='y')
        [FirstLayerIndx, SecondLayerIndx, MinimaZ] = Oxide_getWaterLayerIndicesPerSnap_new(Indx, XYZ, Dens_O, z);
    else 
        error ('Incorrect value for the usr prompt');
    end

    XYZ_snap = zeros(size(XYZ,2), size(XYZ,3));
    XYZ_snap(:,:) = XYZ(snap,:,:);
    
    [VecOH, DistOH] = GetAtomCorrelation(XYZ_snap, AtomIndx.O, AtomIndx.H, ABC);
    
    for j = 1:length(AtomIndx.O)
        BondOH = find(DistOH(:,j) <= 1.28);
        % find the O atoms with ONLY two O-H bonds within 1.3 Ang, i.e. water
        if length(BondOH) == 2
            
            % calculate net vector bisecting H-O-H
            VecH2O = VecOH(BondOH(1),:,j)+VecOH(BondOH(2),:,j);
            
            % % %             % if we want to assume only one direction for both halves if the cell
            zVec = [0 0 1];
            Theta = acosd(dot(VecH2O,zVec)/(norm(VecH2O)*norm(zVec)));
            Phi1 = acosd(dot(VecOH(BondOH(1),:,j),zVec)/(norm(VecOH(BondOH(1),:,j))*norm(zVec)));
            Phi2 = acosd(dot(VecOH(BondOH(2),:,j),zVec)/(norm(VecOH(BondOH(2),:,j))*norm(zVec)));
            
            % split the cell into half and determine if O is in upper or lower half
            % then take the dot product of resultant water vector with both
            % surface normals (assuming the normal is c)
%             if XYZ_snap(AtomIndx.O(j),3) < ABC(3)/2
%                 zVec = [0 0 1];
%                 Theta = acosd(dot(VecH2O,zVec)/(norm(VecH2O)*norm(zVec)));
%                 Phi1 = acosd(dot(VecOH(BondOH(1),:,j),zVec)/(norm(VecOH(BondOH(1),:,j))*norm(zVec)));
%                 Phi2 = acosd(dot(VecOH(BondOH(2),:,j),zVec)/(norm(VecOH(BondOH(2),:,j))*norm(zVec)));
%             elseif XYZ_snap(AtomIndx.O(j),3) >= ABC(3)/2
%                 zVec = [0 0 -1];
%                 Theta = acosd(dot(VecH2O,zVec)/(norm(VecH2O)*norm(zVec)));
%                 Phi1 = acosd(dot(VecOH(BondOH(1),:,j),zVec)/(norm(VecOH(BondOH(1),:,j))*norm(zVec)));
%                 Phi2 = acosd(dot(VecOH(BondOH(2),:,j),zVec)/(norm(VecOH(BondOH(2),:,j))*norm(zVec)));
%             end
            ThetaSnaps{snap,j} = Theta;
            PhiSnaps{snap,j} = [Phi1 Phi2];

        else
            ThetaSnaps{snap,j} = [];
            PhiSnaps{snap,j} = [];
        end

    end
    
    DL_Indx = [SecondLayerIndx{snap}];
    %     DL_Indx = SecondLayerIndx{snap};
    DL_Indx_H = [];
    %     DL_Indx = FirstLayerIndx{snap};
    
    for j = 1:length(DL_Indx)
        BondOH = find(DistOH(:,find(AtomIndx.O == DL_Indx(j))) <= 1.28);
        % find the O atoms with ONLY two O-H bonds within 1.3 Ang, i.e. water
        if length(BondOH) == 2
            
            % calculate net vector bisecting H-O-H
            VecH2O = VecOH(BondOH(1),:,j)+VecOH(BondOH(2),:,j);
            
            % % %             % if we want to assume only one direction for both halves if the cell
            zVec = [0 0 1];
            ThetaDL = acosd(dot(VecH2O,zVec)/(norm(VecH2O)*norm(zVec)));
            Phi1DL = acosd(dot(VecOH(BondOH(1),:,j),zVec)/(norm(VecOH(BondOH(1),:,j))*norm(zVec)));
            Phi2DL = acosd(dot(VecOH(BondOH(2),:,j),zVec)/(norm(VecOH(BondOH(2),:,j))*norm(zVec)));
            
            % split the cell into half and determine if O is in upper or lower half
            % then take the dot product of resultant water vector with both
            % surface normals (assuming the normal is c)
%             if XYZ_snap(DL_Indx(j),3) < ABC(3)/2
%                 zVec = [0 0 1];
%                 ThetaDL = acosd(dot(VecH2O,zVec)/(norm(VecH2O)*norm(zVec)));
%                 Phi1DL = acosd(dot(VecOH(BondOH(1),:,j),zVec)/(norm(VecOH(BondOH(1),:,j))*norm(zVec)));
%                 Phi2DL = acosd(dot(VecOH(BondOH(2),:,j),zVec)/(norm(VecOH(BondOH(2),:,j))*norm(zVec)));
%             elseif XYZ_snap(DL_Indx(j),3) >= ABC(3)/2
%                 zVec = [0 0 -1];
%                 ThetaDL = acosd(dot(VecH2O,zVec)/(norm(VecH2O)*norm(zVec)));
%                 Phi1DL = acosd(dot(VecOH(BondOH(1),:,j),zVec)/(norm(VecOH(BondOH(1),:,j))*norm(zVec)));
%                 Phi2DL = acosd(dot(VecOH(BondOH(2),:,j),zVec)/(norm(VecOH(BondOH(2),:,j))*norm(zVec)));
%             end
        
            ThetaSnapsDL{snap,j} = ThetaDL;
            PhiSnapsDL{snap,j} = [Phi1DL Phi2DL];
        
            DL_Indx_H = [DL_Indx_H; AtomIndx.H(BondOH)];

        else
        
        ThetaSnapsDL{snap,j} = [];
        PhiSnapsDL{snap,j} = [];
        
        DL_Indx_H = [];
        end
    end
    
    % write a sample snapshot to .xyz and convert to MS file (only for last
    % snapshot - simple bug that number of atoms in DL changes so arrays
    % are non-consistent for concatenation when trying to do all snaps together - could modify using cell array)
%     if snap == nConfigs
%         writeSnaptoxyz(BaseFldr, system, snap, XYZ_snap, Atoms, [DL_Indx; DL_Indx_H; AtomIndx.Pts; AtomIndx.Ptb; AtomIndx.Ptss] , 'DL_Density');
%         CP2kOptimPathParse(BaseFldr,system, ['DL_Density_' num2str(snap) '.xyz'])
%     end
    
    DL_Indx = FirstLayerIndx{snap};
    for j = 1:length(DL_Indx)
        BondOH = find(DistOH(:,find(AtomIndx.O == DL_Indx(j))) <= 1.28);
        % find the O atoms with ONLY two O-H bonds within 1.3 Ang, i.e. water
        if length(BondOH) == 2
            
            % calculate net vector bisecting H-O-H
            VecH2O = VecOH(BondOH(1),:,j)+VecOH(BondOH(2),:,j);
            
            % % %             % if we want to assume only one direction for both halves if the cell
            zVec = [0 0 1];
            ThetaDL = acosd(dot(VecH2O,zVec)/(norm(VecH2O)*norm(zVec)));
            Phi1DL = acosd(dot(VecOH(BondOH(1),:,j),zVec)/(norm(VecOH(BondOH(1),:,j))*norm(zVec)));
            Phi2DL = acosd(dot(VecOH(BondOH(2),:,j),zVec)/(norm(VecOH(BondOH(2),:,j))*norm(zVec)));
            
            % split the cell into half and determine if O is in upper or lower half
            % then take the dot product of resultant water vector with both
            % surface normals (assuming the normal is c)
%             if XYZ_snap(DL_Indx(j),3) < ABC(3)/2
%                 zVec = [0 0 1];
%                 ThetaDL = acosd(dot(VecH2O,zVec)/(norm(VecH2O)*norm(zVec)));
%                 Phi1DL = acosd(dot(VecOH(BondOH(1),:,j),zVec)/(norm(VecOH(BondOH(1),:,j))*norm(zVec)));
%                 Phi2DL = acosd(dot(VecOH(BondOH(2),:,j),zVec)/(norm(VecOH(BondOH(2),:,j))*norm(zVec)));
%             elseif XYZ_snap(DL_Indx(j),3) >= ABC(3)/2
%                 zVec = [0 0 -1];
%                 ThetaDL = acosd(dot(VecH2O,zVec)/(norm(VecH2O)*norm(zVec)));
%                 Phi1DL = acosd(dot(VecOH(BondOH(1),:,j),zVec)/(norm(VecOH(BondOH(1),:,j))*norm(zVec)));
%                 Phi2DL = acosd(dot(VecOH(BondOH(2),:,j),zVec)/(norm(VecOH(BondOH(2),:,j))*norm(zVec)));
%             end
        
        ThetaSnaps1stWL{snap,j} = ThetaDL;
        PhiSnaps1stWL{snap,j} = [Phi1DL Phi2DL];

        else
        
        ThetaSnaps1stWL{snap,j} = [];
        PhiSnaps1stWL{snap,j} = [];
        end
        
        %                 DL_Indx_H = [DL_Indx_H; AtomIndx.H(BondOH)];
    end
    
end

% % % % % % % % Theta is the angle of the water H-O-H bisector with the surface normal/c-vector
Theta=vertcat(ThetaSnaps{:,:});
Theta_DL=vertcat(ThetaSnapsDL{:,:}); %I think there is a confusion in the original cause ThetaSnapsDL is only Second WL!!
Theta_1stWL=vertcat(ThetaSnaps1stWL{:,:});

for j = 1:size(ThetaSnapsDL,1)
    nH2O_DL(j) = size(ThetaSnapsDL,2) - nnz(cellfun(@isempty,ThetaSnapsDL(j,:)));
end

for j = 1:size(ThetaSnaps1stWL,1)
    nH2O_1stWL(j) = size(ThetaSnaps1stWL,2) - nnz(cellfun(@isempty,ThetaSnaps1stWL(j,:)));
end

Theta_1st2ndWL=[Theta_1stWL;Theta_DL];

disp(['Ave. number of water molecules in 2nd WL = ' num2str(mean(nH2O_DL)/2) ' ' char(177) ' ' num2str(std(nH2O_DL)/2)]);
disp(['Ave. number of water molecules in 1st WL = ' num2str(mean(nH2O_1stWL)/2) ' ' char(177) ' ' num2str(std(nH2O_1stWL)/2)]);

figure
hold on
title(['H-O-H Bisector Angle Distribution for ' app.system], 'interpreter', 'none')
%%% Global %%%
[f, x] = hist(Theta,60);
% histogram(Theta,60);
plot(x,smooth(smooth(f))/length(AtomIndx.O)/nConfigs, 'k', 'linewidth', 2)

%%% 1st+2nd WL %%%
[f, x] = hist(Theta_1st2ndWL,60);
% histogram(Theta_DL,60);
plot(x,smooth(smooth(f))/length(AtomIndx.O)/nConfigs, 'g', 'linewidth', 2)

%%% 2nd WL %%%
[f, x] = hist(Theta_DL,60);
% histogram(Theta_DL,60);
plot(x,smooth(smooth(f))/length(AtomIndx.O)/nConfigs, 'r', 'linewidth', 2)

%%% 1st WL %%%
[f, x] = hist(Theta_1stWL,60);
% histogram(Theta_DL,60);
plot(x,smooth(smooth(f))/length(AtomIndx.O)/nConfigs, 'b', 'linewidth', 2)

xlabel('\Theta (deg)');
ylabel('Abundance');
% legend('Global', '1st+2nd Layer', '1st Layer');
legend('Global', '1st+2nd Layer', '2nd Layer', '1st Layer');
hold off

% % % % % % % % Phi is the angle of the O-H bond vector made with the surface normal
Phi=reshape(vertcat(PhiSnaps{:}), [2*length(vertcat(PhiSnaps{:})),1]);
Phi_DL=reshape(vertcat(PhiSnapsDL{:}), [2*length(vertcat(PhiSnapsDL{:})),1]);
Phi_1stWL=reshape(vertcat(PhiSnaps1stWL{:}), [2*length(vertcat(PhiSnaps1stWL{:})),1]);

Phi_1st2ndWL=[Phi_1stWL;Phi_DL];

figure
hold on
title(['O-H Angle Distribution for ' app.system], 'interpreter', 'none')

%%% Global %%%
[f, x] = hist(Phi,60);
% histogram(Phi,60);
plot(x,smooth(smooth(f))/length(AtomIndx.O)/2/nConfigs, 'k', 'linewidth', 2)

%%% 1st+2nd WL %%%
[f, x] = hist(Phi_1st2ndWL,60);
% histogram(Phi_DL,60);
plot(x,smooth(smooth(f))/length(AtomIndx.O)/2/nConfigs, 'g', 'linewidth', 2)

%%% 2nd WL %%%
[f, x] = hist(Phi_DL,60);
% histogram(Phi_DL,60);
plot(x,smooth(smooth(f))/length(AtomIndx.O)/2/nConfigs, 'r', 'linewidth', 2)

%%% 1st WL %%%
[f, x] = hist(Phi_1stWL,60);
% histogram(Phi_DL,60);
plot(x,smooth(smooth(f))/length(AtomIndx.O)/2/nConfigs, 'b', 'linewidth', 2)


xlabel('\phi (deg)');
ylabel('Abundance');
legend('Global', '1st+2nd Layer', '2nd Layer', '1st Layer');
hold off
        end

        % Button pushed function: RadialanalysisButton
        function RadialanalysisButtonPushed(app, event)
            % % get the names of atoms from original xyz input file
[~, ~, AtomIndx, ~, ~, ~, ~] = getAtomInfoFromInput(app.BaseFldr, app.system);

% % get the ABC vectors from CP2K input file
ABC = getABCvectors(app.BaseFldr, app.system);

% % Read the xyz data from "Trajectory"
[xyz, XYZ, Indx, Atoms, AtomList, nAtoms, startConfig, nConfigs, StepNum] = ReadAndParsexyz_new(app.BaseFldr, app.system, app.Trajectory, ABC, [0; 0; 0]);


RadFunOH = cell(nConfigs,1);
RadFunFH = cell(nConfigs,1);
RadFunFO = cell(nConfigs,1);
RadFunHH = cell(nConfigs,1);
RadFunOO = cell(nConfigs,1);
RadFunPtO = cell(nConfigs,1);
RadFunPtEO = cell(nConfigs,1);
RadFunPtSO = cell(nConfigs,1);
RadFunAlO = cell(nConfigs,1);
% DistPtO = cell(1,nConfigs);


% for snap = startConfig:startConfig
for snap = startConfig:nConfigs
%     disp(['Processing snapshot ' num2str(StepNum(snap)) ' - ' num2str(100*(snap/nConfigs)) ' % complete']);
    
    XYZ_snap = zeros(size(XYZ,2), size(XYZ,3));
    XYZ_snap(:,:) = XYZ(snap,:,:);
    
% % %     AtomIndx.Omid = find(XYZ_snap(AtomIndx.O,3) < ABC(3)/2+5 & XYZ_snap(AtomIndx.O,3) > ABC(3)/2-5);
% % %    [VecOH, DistOH] = GetAtomCorrelation(XYZ_snap, AtomIndx.Omid, AtomIndx.H, ABC);
% % %    [VecOO, DistOO] = GetAtomCorrelation(XYZ_snap, AtomIndx.Omid, AtomIndx.O, ABC);

    
    [VecOH, DistOH] = GetAtomCorrelation(XYZ_snap, AtomIndx.O, AtomIndx.H, ABC);
%     [VecOH, DistOH] = GetAtomCorrelation(XYZ_snap, [AtomIndx.O; AtomIndx.OtU; AtomIndx.OtL], AtomIndx.H, ABC);
%     [VecFH, DistFH] = GetAtomCorrelation(XYZ_snap, Indx.F, Indx.H, ABC);
%     [VecFO, DistFO] = GetAtomCorrelation(XYZ_snap, Indx.F, Indx.O, ABC);
%     [VecHH, DistHH] = GetAtomCorrelation(XYZ_snap, Indx.H, Indx.H, ABC);
    [VecOO, DistOO] = GetAtomCorrelation(XYZ_snap, AtomIndx.O, AtomIndx.O, ABC);
    [VecAlO, DistAlO] = GetAtomCorrelation(XYZ_snap, AtomIndx.Al1, AtomIndx.O, ABC);
%     [VecPtO, DistPtO] = GetAtomCorrelation(XYZ_snap, AtomIndx.Pts, Indx.O, ABC);

% [VecPt, DistPt] = GetAtomCorrelation(XYZ_snap, AtomIndx.Pts, [AtomIndx.Pts; AtomIndx.Ptss], ABC);
%     RadFunOH{snap} = reshape(DistPt, [numel(DistPt), 1]);
%     
    RadFunOH{snap} = reshape(DistOH, [numel(DistOH), 1]);
%     RadFunFH{snap} = reshape(DistFH, [numel(DistFH), 1]);
%     RadFunFO{snap} = reshape(DistFO, [numel(DistFO), 1]);
%     RadFunHH{snap} = reshape(DistHH, [numel(DistHH), 1]);
    RadFunOO{snap} = reshape(DistOO, [numel(DistOO), 1]);
    RadFunAlO{snap} = reshape(DistAlO, [numel(DistAlO), 1]);
%     RadFunPtO{snap} = reshape(DistPtO{snap}, [numel(DistPtO{snap}), 1]);
    
% [VecPtSO, DistPtSO] = GetAtomCorrelation(XYZ_snap, AtomIndx.Pts, Indx.O, ABC);
%         RadFunPtSO{snap} = reshape(DistPtSO, [numel(DistPtSO), 1]);
% 
%         [VecPtEO, DistPtEO] = GetAtomCorrelation(XYZ_snap, AtomIndx.PtE, Indx.O, ABC);
%         RadFunPtEO{snap} = reshape(DistPtEO, [numel(DistPtEO), 1]);
%         [r1all{snap}, ~] = find(DistPtEO <= MinimaPtEO(1)+0.1);
end

RadialDistribution_new(RadFunOH, ABC, ['O'; 'H'], 1);
% RadialDistribution(RadFunFH, ABC, ['H'; 'F'], 1);
% RadialDistribution(RadFunFO, ABC, ['O'; 'F'], 1);
% RadialDistribution(RadFunHH, ABC, ['H'; 'H'], 1);
RadialDistribution_new(RadFunOO, ABC, ['O'; 'O'], 1);
RadialDistribution_new(RadFunAlO, ABC, ['Al'; 'O '], 1);  
% MinimaPtSO = RadialDistribution(RadFunPtSO, ABC, ['Pts'; 'O  '],1);
% [r1stflat, ~] = find(DistPtSO <= MinimaPtSO(1));
% 
% MinimaPtEO = RadialDistribution(RadFunPtEO, ABC, ['PtE'; 'O  '],1);
% [r1st, ~] = find(DistPtEO <= MinimaPtEO(1));
% 
% 
% [~, DistOH1stWL] = GetAtomCorrelation(XYZ_snap, AtomIndx.O(r1st), Indx.H, ABC);
 
 return

        DL1st{i} = Indx.O(unique(r1st));
        [r2nd, ~] = find(DistPtO{i} <= MinimaPtO(2) & DistPtO{i} > MinimaPtO(1));
        DL2nd{i} = Indx.O(unique(r2nd));
        nonDL{i} = setdiff(Indx.O, [DL1st{i}; DL2nd{i}]);
        
        XYZ_snap = zeros(size(XYZ,2), size(XYZ,3));
        XYZ_snap(:,:) = XYZ(i,:,:);
        
        [~, DistOH1stWL] = GetAtomCorrelation(XYZ_snap, DL1st{i}, Indx.H, ABC);
        [~, DistOH2ndWL] = GetAtomCorrelation(XYZ_snap, DL2nd{i}, Indx.H, ABC);
        [~, DistOHnonDL] = GetAtomCorrelation(XYZ_snap, nonDL{i}, Indx.H, ABC);
        
        
        AvePos = mean(xyz.O(:, r1st, :),1)
        figure
        hold on
%         plot3(AvePos(1,:,1),AvePos(1,:,2),AvePos(1,:,3),'o', 'markerfacecolor', 'r', 'markeredgecolor', 'k', 'markersize', 15)
%         text(AvePos(1,:,1),AvePos(1,:,2),AvePos(1,:,3), num2str(r1st))
        for i = 1:length(r1st)
            plot3(xyz.O(:,r1st(i),1), xyz.O(:,r1st(i),2), xyz.O(:,r1st(i),3), 'o', 'markerfacecolor', 'r', 'markeredgecolor', 'k', 'markersize', 5)
        end
        %         plot3(xyz.O(:,614,1), xyz.O(:,614,2), xyz.O(:,614,3), 'o', 'markerfacecolor', 'b', 'markeredgecolor', 'k', 'markersize', 15)
        
        r1stflat = setdiff(r1stflat,r1st);
        AvePos1stflat = mean(xyz.O(:, r1stflat, :),1)
%         plot3(AvePos1stflat(1,:,1),AvePos1stflat(1,:,2),AvePos1stflat(1,:,3),'o', 'markerfacecolor', 'm', 'markeredgecolor', 'k', 'markersize', 15)
%        plot3(xyz.O(end,r1stflat,1), xyz.O(end,r1stflat,2), xyz.O(end,r1stflat,3), 'o', 'markerfacecolor', 'm', 'markeredgecolor', 'k', 'markersize', 15)
        
        for i = 1:length(r1stflat)
            plot3(xyz.O(:,r1stflat(i),1), xyz.O(:,r1stflat(i),2), xyz.O(:,r1stflat(i),3), 'o', 'markerfacecolor', 'm', 'markeredgecolor', 'k', 'markersize', 5)
        end
        
                AvePosPtE = mean(xyz.Pt(:, AtomIndx.PtE, :),1)
        plot3(AvePosPtE(1,:,1),AvePosPtE(1,:,2),AvePosPtE(1,:,3),'o', 'markerfacecolor', 'c', 'markeredgecolor', 'k', 'markersize', 30)
        
        AvePosPtS = mean(xyz.Pt(:, AtomIndx.Pts, :),1)
        plot3(AvePosPtS(1,:,1),AvePosPtS(1,:,2),AvePosPtS(1,:,3),'o', 'markerfacecolor', 'b', 'markeredgecolor', 'k', 'markersize', 30)

        
        [r2nd, ~] = find(DistPtEO > MinimaPtEO(1) & DistPtEO <= MinimaPtEO(2));
        r2nd = setdiff(r2nd,r1st);
        AvePos2nd = mean(xyz.O(:, r2nd, :),1)
        
        plot3(AvePos2nd(1,:,1),AvePos2nd(1,:,2),AvePos2nd(1,:,3),'o', 'markerfacecolor', [0 0.5 0], 'markeredgecolor', 'k', 'markersize', 15)

        end

        % Button pushed function: BaderHeatmapButton
        function BaderHeatmapButtonPushed(app, event)
fldrname = [app.BaseFldr app.system '/Bader_Analysis/'];
ACFfiles = dir([fldrname 'ACF_*.dat']);


% % Call function to find ABC vectors from .inp file
ABC = getABCvectors(app.BaseFldr, app.system);

% % get the names of atoms from original xyz input file
[Atoms, AtomList, Indx, Indxfns, Kinds, Elements, PP] = getAtomInfoFromInput(app.BaseFldr, app.system);

% % find the indices of atoms containing myAtoms
myAtoms = {'Al'};
% myAtoms = {'all'};
[Indx, myAtomList, myAtomNums, myAtomCore] = detectAtomsOfType(myAtoms, AtomList, Indx, Indxfns, Kinds, Elements, PP);

% % parse coordinates of atoms along trajectory and wrap into cell
% [xyz, XYZ, ~, ~, ~, nAtoms, startConfig, nConfigs, StepNum_Traj] = ReadAndParsexyz(BaseFldr, system, Trajectory, ABC, [0 0 0]);
[xyz, XYZ, ~, ~, ~, nAtoms, startConfig, nConfigs, StepNum_Traj] = ReadAndParsexyz_new(app.BaseFldr, app.system, app.Trajectory, ABC, [0 0 0]);

% % Read the Bader charge "ACF" files and extract the raw charge Q/net charge Qnet
Q = zeros(length(Atoms),length(ACFfiles));
Qnet = zeros(length(Atoms),length(ACFfiles));

for n = 1:length(ACFfiles)
    disp(['Extracting charge from ' ACFfiles(n).name]);
    [Q(:,n), Qnet(:,n), StepNum(n)] = extractBaderCharges(fldrname, ACFfiles(n).name, Atoms, AtomList, Kinds, PP);
end

MeanCharge = zeros(length(AtomList),length(StepNum));
StdCharge = zeros(length(AtomList),length(StepNum));
SumCharge = zeros(length(AtomList),length(StepNum));

for i = 1:length(StepNum)
    for j = 1:length(AtomList)
        MeanCharge(j,i) = mean(Qnet(Indx.(Indxfns{j}),i));
        StdCharge(j,i) = std(Qnet(Indx.(Indxfns{j}),i));
        SumCharge(j,i) = sum(Qnet(Indx.(Indxfns{j}),i));
    end
end

[StepNum,sortIdx] = sort(StepNum,'ascend');
SumCharge = SumCharge(:,sortIdx);
MeanCharge = MeanCharge(:,sortIdx);
StdCharge = StdCharge(:,sortIdx);

XYZ_snap = reshape(XYZ(1,:,:), [size(XYZ,2) size(XYZ,3)]);

MeanQnet = mean(Qnet(myAtomNums,:),2);
Bader3DCharge(XYZ_snap(myAtomNums,:), ABC, MeanQnet);
        end

        % Button pushed function: OHbulkcovButton
        function OHbulkcovButtonPushed(app, event)
% % Call function to find ABC vectors from .inp file
ABC = getABCvectors(app.BaseFldr, app.system);

% % get the names of atoms from original xyz input file
[Atoms, AtomList, Indx, Indxfns, Kinds, Elements, PP] = getAtomInfoFromInput(app.BaseFldr, app.system);

% % parse coordinates of atoms along trajectory and wrap into cell
[xyz, XYZ, ~, ~, ~, nAtoms, startConfig, nConfigs, StepNum_Traj] = ReadAndParsexyz_new(app.BaseFldr, app.system, app.Trajectory, ABC, [0 0 0]);

OH_Coverage = zeros(nConfigs, 1);
H2O_Coverage = zeros(nConfigs, 1);
H3O_Coverage = zeros(nConfigs, 1);
O_Coverage = zeros(nConfigs, 1);


for i = 1:nConfigs

    XYZ_snap = zeros(size(XYZ,2), size(XYZ,3));
    XYZ_snap(:,:) = XYZ(i,:,:);

    % get the distances between pairs of atoms
    [~, DistAlO] = GetAtomCorrelation(XYZ_snap, Indx.Al1, Indx.O, ABC);
    [r,c] = find(DistAlO < 2.85); % Rashid to fix this "2" by looking at RDF minimum - r = row aka O atom number, c = column aka Al1 atom number

    [~, DistOH] = GetAtomCorrelation(XYZ_snap, Indx.H, setdiff(Indx.O,Indx.O(r)), ABC);
%     [~, DistOH] = GetAtomCorrelation(XYZ_snap, Indx.H, Indx.O, ABC);
    [rOH,cOH] = find(DistOH < 1.28);
    [GR, GC] = groupcounts(rOH);
    O_Coverage(i) = sum(GR == 0);
    OH_Coverage(i) = sum(GR == 1);
    H2O_Coverage(i) = sum(GR == 2);
    
    [gH3O, cH3O]= groupcounts(cOH);
    H3O_Coverage(i) = sum(GR == 3)-sum(gH3O==2); %Potential bug, we are picking up any shared H atom shared between two O atoms that might not be H3O and it might be a H shuttling between OH and H2O!
%One idea is to loop over the shared oxygens and then identify any OH
%molecules and if there are any then they would be excluded from the
%subtraction somehow, i.e. we want to only remove shared O between two
%H2O's and nothing else, those are the suspected double countings.
   
    
%     Indx.O(cOH(GC(GR==1))) 
end

%----- This is ugly but works, fix it at some point -----

O_OH_vmd_indx = zeros(nConfigs, 1);
H_OH_vmd_indx = zeros(nConfigs, 1);
O_H3O_vmd_indx = zeros(nConfigs, 1);

for i = 1:nConfigs
XYZ_snap = zeros(size(XYZ,2), size(XYZ,3));
XYZ_snap(:,:) = XYZ(i,:,:);
% get the distances between pairs of atoms
[~, DistAlO] = GetAtomCorrelation(XYZ_snap, Indx.Al1, Indx.O, ABC);
[r,c] = find(DistAlO < 2.85); % Rashid to fix this "2" by looking at RDF minimum - r = row aka O atom number, c = column aka Al1 atom number
[~, DistOH] = GetAtomCorrelation(XYZ_snap, Indx.H, Indx.O, ABC);
%     [~, DistOH] = GetAtomCorrelation(XYZ_snap, Indx.H, Indx.O, ABC);
[rOH,cOH] = find(DistOH < 1.28);
[GR, GC] = groupcounts(rOH);

disp(['config: '  num2str(i) ' of ' num2str(nConfigs)])
%----OH--condition
cond_OH=cOH(GC(GR==1));
% OH_Mat_indx=Indx.O(cOH(GC(GR==1)));
OH_O_Mat_indx=Indx.O(cond_OH);
OH_H_Mat_indx=Indx.H(cOH(ismember(rOH,cond_OH)));
OH_O_vmd_indx=OH_O_Mat_indx-1
OH_H_vmd_indx=OH_H_Mat_indx-1
% O_OH_vmd_indx(i)=OH_O_vmd_indx;
% H_OH_vmd_indx(i)=OH_H_vmd_indx;


%----H3O--condition
cond_H3O=cOH(GC(GR==3));
% H3O_Mat_indx=Indx.O(cOH(GC(GR==3)));
H3O_O_Mat_indx=Indx.O(cond_H3O);
H3O_H_Mat_indx=Indx.H(cOH(ismember(rOH,cond_H3O)));
H3O_O_vmd_indx=H3O_O_Mat_indx-1
H3O_H_vmd_indx=H3O_H_Mat_indx-1
% O_H3O_vmd_indx(i)=H3O_O_vmd_indx;
end

%-------------------------------------------------------

Total_Coverage = OH_Coverage+H2O_Coverage+H3O_Coverage;

%H2O,H3O,OH and total 
figure
hold on
plot((1:nConfigs)*0.5, OH_Coverage, '-ok', 'markerfacecolor', 'r')
plot((1:nConfigs)*0.5, H2O_Coverage, '-ok', 'markerfacecolor', 'b')
plot((1:nConfigs)*0.5, H3O_Coverage, '-ok', 'markerfacecolor', 'auto')
plot((1:nConfigs)*0.5, Total_Coverage, '-ok', 'markerfacecolor', [0 0.8 0])
% plot((1:nConfigs)/200, OH_Coverage, '-ok', 'markerfacecolor', 'r')
% plot((1:nConfigs)/200, H2O_Coverage, '-ok', 'markerfacecolor', 'b')
% plot((1:nConfigs)/200, H3O_Coverage, '-ok', 'markerfacecolor', 'auto')
% plot((1:nConfigs)/200, Total_Coverage, '-ok', 'markerfacecolor', [0 0.8 0])
legend('OH', 'Water','H3O', 'Total')
xlabel('Time (ps)')
ylabel('Number of Molecules')

%H3O and OH only
figure
hold on
plot((1:nConfigs)*0.5, OH_Coverage, '-ok', 'markerfacecolor', 'r')
plot((1:nConfigs)*0.5, H3O_Coverage, '-ok', 'markerfacecolor', 'auto')
% plot((1:nConfigs)/200, OH_Coverage, '-ok', 'markerfacecolor', 'r')
% plot((1:nConfigs)/200, H3O_Coverage, '-ok', 'markerfacecolor', 'auto')
legend('OH','H3O')
xlabel('Time (ps)')
ylabel('Number of Molecules')


%Stats
disp(['Ave. coverage of OH = ' num2str(mean(OH_Coverage(1:end)/108), '%.2f') ' +/- ' num2str(std(OH_Coverage(1:end)/108), '%.2f') ' ML'])
disp(['Ave. coverage of H2O = ' num2str(mean(H2O_Coverage(1:end)/108), '%.2f') ' +/- ' num2str(std(H2O_Coverage(1:end)/108), '%.2f') ' ML'])
disp(['Ave. coverage of H3O = ' num2str(mean(H3O_Coverage(1:end)/108), '%.2f') ' +/- ' num2str(std(H3O_Coverage(1:end)/108), '%.2f') ' ML'])
disp(['Ave. Total coverage = ' num2str(mean(Total_Coverage(1:end)/108), '%.2f') ' +/- ' num2str(std(Total_Coverage(1:end)/108), '%.2f') ' ML'])
disp(['Ave. O coverage = ' num2str(mean(O_Coverage(1:end)/108), '%.2f') ' +/- ' num2str(std(O_Coverage(1:end)/108), '%.2f') ' ML'])
        end

        % Button pushed function: OHsurfacecovButton
        function OHsurfacecovButtonPushed(app, event)
% % Call function to find ABC vectors from .inp file
ABC = getABCvectors(app.BaseFldr, app.system);

% % get the names of atoms from original xyz input file
[Atoms, AtomList, Indx, Indxfns, Kinds, Elements, PP] = getAtomInfoFromInput(app.BaseFldr, app.system);

% % parse coordinates of atoms along trajectory and wrap into cell
[xyz, XYZ, ~, ~, ~, nAtoms, startConfig, nConfigs, StepNum_Traj] = ReadAndParsexyz_new(app.BaseFldr, app.system, app.Trajectory, ABC, [0 0 0]);

OH_Coverage = zeros(nConfigs, 1);
H2O_Coverage = zeros(nConfigs, 1);
H3O_Coverage = zeros(nConfigs, 1);
O_Coverage = zeros(nConfigs, 1);

for i = 1:nConfigs

    XYZ_snap = zeros(size(XYZ,2), size(XYZ,3));
    XYZ_snap(:,:) = XYZ(i,:,:);

    % get the distances between pairs of atoms
    [~, DistAlO] = GetAtomCorrelation(XYZ_snap, Indx.Al1, Indx.O, ABC);
    [r,c] = find(DistAlO < 2.85); % Rashid to fix this "2" by looking at RDF minimum - r = row aka O atom number, c = column aka Al1 atom number

    [~, DistOH] = GetAtomCorrelation(XYZ_snap, Indx.H, Indx.O(r), ABC);
    [rOH,cOH] = find(DistOH < 1.28);
    [GR, GC] = groupcounts(rOH);
    O_Coverage(i) = sum(GR == 0);
    OH_Coverage(i) = sum(GR == 1);
    H2O_Coverage(i) = sum(GR == 2); 
    H3O_Coverage(i) = sum(GR == 3);


end

Total_Coverage = OH_Coverage+H2O_Coverage;

figure
hold on
% plot((1:nConfigs)*0.5, OH_Coverage, '-ok', 'markerfacecolor', 'r')
% plot((1:nConfigs)*0.5, H2O_Coverage, '-ok', 'markerfacecolor', 'b')
% plot((1:nConfigs)*0.5, Total_Coverage, '-ok', 'markerfacecolor', [0 0.8 0])
plot((1:nConfigs)/2000, OH_Coverage, '-ok', 'markerfacecolor', 'r')
plot((1:nConfigs)/2000, H2O_Coverage, '-ok', 'markerfacecolor', 'b')
plot((1:nConfigs)/2000, Total_Coverage, '-ok', 'markerfacecolor', [0 0.8 0])
legend('OH', 'Water', 'Total')
xlabel('Time (ps)')
ylabel('Number of Molecules')

disp(['Ave. coverage of OH = ' num2str(mean(OH_Coverage(1:end)/108), '%.2f') ' +/- ' num2str(std(OH_Coverage(1:end)/108), '%.2f') ' ML'])
disp(['Ave. coverage of H2O = ' num2str(mean(H2O_Coverage(1:end)/108), '%.2f') ' +/- ' num2str(std(H2O_Coverage(1:end)/108), '%.2f') ' ML'])
disp(['Ave. coverage of H3O = ' num2str(mean(H3O_Coverage(1:end)/108), '%.2f') ' +/- ' num2str(std(H3O_Coverage(1:end)/108), '%.2f') ' ML'])
disp(['Ave. Total coverage = ' num2str(mean(Total_Coverage(1:end)/108), '%.2f') ' +/- ' num2str(std(Total_Coverage(1:end)/108), '%.2f') ' ML'])
disp(['Ave. O coverage = ' num2str(mean(O_Coverage(1:end)/108), '%.2f') ' +/- ' num2str(std(O_Coverage(1:end)/108), '%.2f') ' ML'])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BaseFolderLabel = uilabel(app.UIFigure);
            app.BaseFolderLabel.Text = 'Base Folder ';
            app.BaseFolderEditField = uieditfield(app.UIFigure, 'text');
            app.BaseFolderEditField.ValueChangedFcn = createCallbackFcn(app, @BaseFolderEditFieldValueChanged, true);
            app.SystemLabel = uilabel(app.UIFigure);
            app.SystemLabel.Text = 'System        ';
            app.SystemEditField = uieditfield(app.UIFigure, 'text');
            app.SystemEditField.ValueChangedFcn = createCallbackFcn(app, @SystemEditFieldValueChanged, true);
            app.TrajectoryEditFieldLabel = uilabel(app.UIFigure);
            app.TrajectoryEditFieldLabel.Text = 'Trajectory    ';
            app.TrajectoryEditField = uieditfield(app.UIFigure, 'text');
            app.TrajectoryEditField.ValueChangedFcn = createCallbackFcn(app, @TrajectoryEditFieldValueChanged, true);
            app.SplitXYZfileButton = uibutton(app.UIFigure, 'push');
            app.SplitXYZfileButton.ButtonPushedFcn = createCallbackFcn(app, @SplitXYZfileButtonPushed, true);
            app.SplitXYZfileButton.Text = 'Split XYZ file';
            app.PaseEnergyfileButton = uibutton(app.UIFigure, 'push');
            app.PaseEnergyfileButton.ButtonPushedFcn = createCallbackFcn(app, @PaseEnergyfileButtonPushed, true);
            app.PaseEnergyfileButton.Text = 'Pase Energy file';
            app.MassDensityplotButton = uibutton(app.UIFigure, 'push');
            app.MassDensityplotButton.ButtonPushedFcn = createCallbackFcn(app, @MassDensityplotButtonPushed, true);
            app.MassDensityplotButton.Text = 'Mass Density plot';
            app.BaderHeatmapButton = uibutton(app.UIFigure, 'push');
            app.BaderHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @BaderHeatmapButtonPushed, true);
            app.BaderHeatmapButton.Text = 'Bader Heat map';
            app.RadialanalysisButton = uibutton(app.UIFigure, 'push');
            app.RadialanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RadialanalysisButtonPushed, true);
            app.RadialanalysisButton.Text = 'Radial analysis';
            app.AngularDistButton = uibutton(app.UIFigure, 'push');
            app.AngularDistButton.ButtonPushedFcn = createCallbackFcn(app, @AngularDistButtonPushed, true);
            app.AngularDistButton.Text = 'Angular Dist';
            app.OHsurfacecovButton = uibutton(app.UIFigure, 'push');
            app.OHsurfacecovButton.ButtonPushedFcn = createCallbackFcn(app, @OHsurfacecovButtonPushed, true);
            app.OHsurfacecovButton.Text = 'OH surface cov.';
            app.OHbulkcovButton = uibutton(app.UIFigure, 'push');
            app.OHbulkcovButton.ButtonPushedFcn = createCallbackFcn(app, @OHbulkcovButtonPushed, true);
            app.OHbulkcovButton.Text = 'OH bulk cov.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu2_CCG_MolSim_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end