classdef Step2_MassDensity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotButton                matlab.ui.control.StateButton
        TrajectoryEditField       matlab.ui.control.EditField
        TrajectoryLabel           matlab.ui.control.Label
        SystemEditField           matlab.ui.control.EditField
        SystemLabel               matlab.ui.control.Label
        BaseFolderEditField       matlab.ui.control.EditField
        BaseFolderEditFieldLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        BaseFldr % Description
        system
        Trajectory
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BaseFolderEditField
        function BaseFolderEditFieldValueChanged(app, event)
            app.BaseFldr = app.BaseFolderEditField.Value;
            
        end

        % Value changed function: SystemEditField
        function SystemEditFieldValueChanged(app, event)
            app.system = app.SystemEditField.Value;
            
        end

        % Value changed function: TrajectoryEditField
        function TrajectoryEditFieldValueChanged(app, event)
            app.Trajectory = app.TrajectoryEditField.Value;
            
        end

        % Value changed function: PlotButton
        function PlotButtonValueChanged(app, event)
            value = app.PlotButton.Value;
           
            % % get the names of atoms from original xyz input file
[~, ~, AtomIndx, ~, ~, ~, ~] = getAtomInfoFromInput(app.BaseFldr, app.system);

ABC = getABCvectors(app.BaseFldr, app.system);
% [xyz, XYZ, Indx, ~, ~, nAtoms, startConfig, nConfigs, StepNum] = ReadAndParsexyz(BaseFldr, system, Trajectory, ABC, [pi/2; 1; 1]);
[xyz, XYZ, Indx, Atoms, AtomList, nAtoms, startConfig, nConfigs, StepNum] = ReadAndParsexyz_new(app.BaseFldr, app.system, app.Trajectory, ABC, [0; 0; 0]);
% [xyz, XYZ, Indx, Atoms, AtomList, nAtoms, startConfig, nConfigs, StepNum] = ReadAndParsexyz_new(BaseFldr, system, Trajectory, ABC, [pi/2; 1; 1]);


% % % % % %% Modify which O atoms go into mass density accoring to explicit naming in
% % % % % % input xyz. AtomIndx is the Index of atoms by name from input.xyz. Modify
% % % % % % % get the names of atoms from original xyz input file
% % % % % % [~, OIndxxyz] = ismember([AtomIndx.O; AtomIndx.OtL; AtomIndx.OtU; AtomIndx.OtS] , Indx.O);
% % % % % [~, OIndxxyz] = ismember([AtomIndx.O] , Indx.O);
% % % % % xyz.O = xyz.O(:,OIndxxyz,:);
%%

[Dens_O, Dens_H, TotDen, AveDen, z] = getDensityProfile(xyz, ABC);
% [Dens_O, Dens_H, TotDen, AveDen, z] = getCylindricalDensity(xyz, ABC);
% [Dens_O, Dens_H, Dens_F, TotDen, AveDen, z] = getDensityProfile(xyz, ABC);
% [Dens_O, Dens_H, Dens_Na, Dens_Cl, TotDen, AveDen, z] = getDensityProfile(xyz, ABC);

% [FirstLayerIndx, SecondLayerIndx, ThirdLayerIndx, FourthLayerIndx, MinimaZ] = getWaterLayerIndices(Indx, XYZ, Dens_O, z);

zmax = ABC(3);
bins = length(z) + 1;
% Plot each snapshot
figure
hold on
set(gcf, 'position', [377         423        1123         420]);
xlabel('z (Ang)');
ylabel('Density (kgm^{-3})');
set(gca, 'xlim', [0 ABC(3)], 'ylim', [0 2500]);
% include profile for every snapshot
for i = 1:size(TotDen,2)
    plot(z, TotDen(:,i), '--', 'linewidth', 0.25)
%     plot(z, TotDen(:,i), '--', 'linewidth', 0.25, 'color', 'r')
end
plot(z, sum(TotDen,2)/(nConfigs-startConfig+1), 'linewidth', 1.5, 'color', 'k')
plot([z(1) z(end)], [mean(AveDen) mean(AveDen)], ':', 'color', [0.6 0.6 0.6])
plot([z((bins/2)-round(3/(zmax/(bins-1))/2)) z((bins/2)-round(3/(zmax/(bins-1))/2))], [0 2500], ':', 'color', [0.6 0.6 0.6])
plot([z((bins/2)+round(3/(zmax/(bins-1))/2)) z((bins/2)+round(3/(zmax/(bins-1))/2))], [0 2500], ':', 'color', [0.6 0.6 0.6])
hold off

figure
hold on
set(gcf, 'position', [377         423        1123         420]);
xlabel('z (Ang)');
ylabel('Density (kgm^{-3})');
set(gca, 'xlim', [0 ABC(3)], 'ylim', [0 2500]);

plot(app.UIAxes,z, sum(TotDen,2)/(nConfigs-startConfig+1), 'linewidth', 1.5, 'color', 'k')
plot(app.UIAxes,z, sum(Dens_H,2)/(nConfigs-startConfig+1), 'linewidth', 1.5, 'color', 'b')
plot(app.UIAxes,z, sum(Dens_O,2)/(nConfigs-startConfig+1), 'linewidth', 1.5, 'color', 'r')

plot(app.UIAxes,[z(1) z(end)], [mean(AveDen) mean(AveDen)], ':', 'color', [0.6 0.6 0.6])
plot(app.UIAxes,[z((bins/2)-round(5/(zmax/(bins-1))/2)) z((bins/2)-round(5/(zmax/(bins-1))/2))], [0 2500], ':', 'color', [0.6 0.6 0.6]) % there might be a bug here since zmax isn't max(z)? 
plot(app.UIAxes,[z((bins/2)+round(5/(zmax/(bins-1))/2)) z((bins/2)+round(5/(zmax/(bins-1))/2))], [0 2500], ':', 'color', [0.6 0.6 0.6])

legend('Water', 'H', 'O', 'Ave. Bulk Density', 'location', 'northeast');

hold off


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Z (Å)')
            ylabel(app.UIAxes, 'Density (kg/m^3)')
            zlabel(app.UIAxes, 'Z')
            app.BaseFolderEditFieldLabel = uilabel(app.UIFigure);
            app.BaseFolderEditFieldLabel.Text = 'Base Folder';
            app.BaseFolderEditField = uieditfield(app.UIFigure, 'text');
            app.BaseFolderEditField.ValueChangedFcn = createCallbackFcn(app, @BaseFolderEditFieldValueChanged, true);
            app.SystemLabel = uilabel(app.UIFigure);
            app.SystemLabel.Text = 'System       ';
            app.SystemEditField = uieditfield(app.UIFigure, 'text');
            app.SystemEditField.ValueChangedFcn = createCallbackFcn(app, @SystemEditFieldValueChanged, true);
            app.TrajectoryLabel = uilabel(app.UIFigure);
            app.TrajectoryLabel.Text = 'Trajectory   ';
            app.TrajectoryEditField = uieditfield(app.UIFigure, 'text');
            app.TrajectoryEditField.ValueChangedFcn = createCallbackFcn(app, @TrajectoryEditFieldValueChanged, true);
            app.PlotButton = uibutton(app.UIFigure, 'state');
            app.PlotButton.ValueChangedFcn = createCallbackFcn(app, @PlotButtonValueChanged, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Step2_MassDensity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end